classdef LabS6_FIR_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        DesignFilterButton       matlab.ui.control.Button
        OrderEditField           matlab.ui.control.NumericEditField
        OrderEditFieldLabel      matlab.ui.control.Label
        CutoffFEditField         matlab.ui.control.NumericEditField
        CutoffFEditFieldLabel    matlab.ui.control.Label
        SamplingFEditField       matlab.ui.control.NumericEditField
        SamplingFEditFieldLabel  matlab.ui.control.Label
        WindowTypeDropDown       matlab.ui.control.DropDown
        WindowTypeDropDownLabel  matlab.ui.control.Label
        FilterTypeDropDown       matlab.ui.control.DropDown
        FilterTypeDropDownLabel  matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        function w = HammingWindow(app, N)
            % Custom implementation of the Hamming window
            n = 0:(N-1); % Index range
            w = 0.54 - 0.46 * cos(2 * pi * n / (N - 1)); % Hamming formula
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DesignFilterButton
        function DesignFilterButtonPushed(app, event)

    % Retrieve inputs from user
    M = app.OrderEditField.Value; % Filter Order
    fs = app.SamplingFEditField.Value; % Sampling Frequency
    fc = app.CutoffFEditField.Value; % Cutoff Frequency
    windowType = app.WindowTypeDropDown.Value; % Window Type

    % Compute normalized cutoff frequency
    wc = 2 * fc / fs; % Normalize cutoff to [0, 1] range for freqz

    % Generate filter coefficients
    n = 0:M;
    ideal_h = sin(wc * pi * (n - M/2)) ./ (pi * (n - M/2));
    ideal_h(M/2 + 1) = wc; % Fix division by zero at center
    
    % Apply the selected window
    switch windowType
        case 'Rectangular'
            window = ones(1, M + 1);
        case 'Hamming'
            window = app.HammingWindow(M + 1); % Use the custom Hamming function
    end
    h = ideal_h .* window;

    % Compute frequency response
    [H, f] = freqz(h, 1, 1024, fs); % Frequency response in Hz
    magnitude_dB = 20 * log10(abs(H) + eps); % Convert to dB

    % Plot frequency response
    plot(app.UIAxes, f, magnitude_dB, 'LineWidth', 1.5);
    title(app.UIAxes, 'Frequency Response (dB)');
    xlabel(app.UIAxes, 'Frequency (Hz)');
    ylabel(app.UIAxes, 'Magnitude (dB)');
    grid(app.UIAxes, 'on');


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FilterTypeDropDownLabel = uilabel(app.LeftPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.LeftPanel);
            app.WindowTypeDropDownLabel = uilabel(app.LeftPanel);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.LeftPanel);
            app.WindowTypeDropDown.Items = {'Rectangular', 'Hamming'};
            app.WindowTypeDropDown.Value = 'Rectangular';
            app.SamplingFEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingFEditFieldLabel.Text = 'Sampling F';
            app.SamplingFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CutoffFEditFieldLabel = uilabel(app.LeftPanel);
            app.CutoffFEditFieldLabel.Text = 'Cutoff F';
            app.CutoffFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OrderEditFieldLabel = uilabel(app.LeftPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DesignFilterButton = uibutton(app.LeftPanel, 'push');
            app.DesignFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DesignFilterButtonPushed, true);
            app.DesignFilterButton.Text = 'Design Filter';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabS6_FIR_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end